classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DefectDetectionAppPanel  matlab.ui.container.Panel
        DefectDetectorAllRightsReservedLabel  matlab.ui.control.Label
        statusLamp               matlab.ui.control.Lamp
        AdvancedFunctionsPanel   matlab.ui.container.Panel
        ExcelSheetCheckBox       matlab.ui.control.CheckBox
        BarChartCheckBox         matlab.ui.control.CheckBox
        ResultsButton            matlab.ui.control.Button
        PredictedLabel           matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        AppStatusLabel           matlab.ui.control.Label
        DataVisualisationPanel   matlab.ui.container.Panel
        Slider                   matlab.ui.control.Slider
        GraphType                matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        MainControlPanel         matlab.ui.container.Panel
        ResetButton              matlab.ui.control.Button
        DisplaygraphButton       matlab.ui.control.Button
        ModelDropDown            matlab.ui.control.DropDown
        PlaybackButton           matlab.ui.control.Button
        ClassifyaudioButton      matlab.ui.control.Button
        LoadAudioButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
       audioFile
       audioPath 
       predictedLabels= categorical();
       trueLabels= categorical();
       
    end
    
    properties (Access = private)
        audioData
        fs
        lastScores
        lastExtractedFeatures table
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Set initial lamp and status
    app.AppStatusLabel.Text = 'Ready';
    app.AppStatusLabel.FontColor = [0.5 0.5 0.5];
    app.statusLamp.Color = [0.5 0.5 0.5];


        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
 

            function startupFcn(app)
    app.AppStatusLabel.Text = 'Ready';
    app.AppStatusLabel.FontColor = [0.5 0.5 0.5];
    app.statusLamp.Color = [0.5 0.5 0.5];  % Grey
    end
    startupFcn(app)
    % File selection dialog
    [app.audioFile, app.audioPath] = uigetfile('*.wav', 'Select an audio file');
    
    % If user cancels
    if isequal(app.audioFile, 0)
        app.AppStatusLabel.Text = 'Cancelled';
        app.statusLamp.Color = 'red';
        return;
    end

    % Read the selected audio file
    [signal, app.fs] = audioread(fullfile(app.audioPath, app.audioFile));
    app.audioData = signal;

    % Update status: Audio loaded
    app.AppStatusLabel.Text = 'Loaded Audio';
    app.statusLamp.Color = 'yellow';

    % Plot waveform
    t = (0:length(signal) - 1) / app.fs;
    plot(app.UIAxes, t, signal, "r");
    title(app.UIAxes, 'Waveform');
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Amplitude');
    grid(app.UIAxes, 'on');


        end

        % Button pushed function: PlaybackButton
        function PlaybackButtonPushed(app, event)
            app.audioFile
            % Check if audio file is loaded
    if isempty(app.audioFile) || isempty(app.audioPath)
        uialert(app.UIFigure, 'Please load an audio file first.', 'Playback Error');
        return;
    end
            %1 read audiofile using audioread
            [signal, app.fs] =audioread(fullfile(app.audioPath,app.audioFile));
            %2 play the file using the sound function
            sound(signal,app.fs)
        end

        % Button pushed function: ClassifyaudioButton
        function ClassifyaudioButtonPushed(app, event)
 
% STEP 1: Check if audio is loaded
if isempty(app.audioData)
    uialert(app.UIFigure, 'No audio loaded!', 'Error');
    return;
end


% STEP 2: Extract features from the loaded audio
features = extractAudioFeatures(app.audioData, app.fs);
disp(features.Properties.VariableNames); 
app.lastExtractedFeatures = features;     

% STEP 3: Get the selected model from the dropdown
modelName = upper(strtrim(app.ModelDropDown.Value));
disp("Selected model: " + modelName);  

switch modelName
    case 'TREE'
        load('TreeModel.mat', 'TreeModel');
        [predictedLabel, ~] = classifyCompressorFault(fullfile(app.audioPath, app.audioFile), TreeModel);

    case 'SVM'
        load('bestModel.mat', 'trainedModel');
        [predictedLabel, ~] = classifyCompressorFault(fullfile(app.audioPath, app.audioFile), trainedModel);

    case 'ENSEMBLE'
        load('EnsembleModel.mat', 'EnsembleModel');
        [predictedLabel, ~] = classifyCompressorFault(fullfile(app.audioPath, app.audioFile), EnsembleModel);
end

% STEP 5: Save labels for comparison and visual feedback
labelStr = string(predictedLabel);

% Append predicted label
if iscategorical(predictedLabel)
    app.predictedLabels(end+1) = predictedLabel;
else
    app.predictedLabels(end+1) = categorical(string(predictedLabel));
end

% Get true label from file name
[~, name, ~] = fileparts(app.audioFile);
if contains(name, "_")
    labelFromName = extractBefore(name, "_");
else
    labelFromName = "Unknown";
end
app.trueLabels(end+1) = categorical(cellstr(labelFromName));

% STEP 6: Display predicted label and lamp color
app.Lamp.Visible = true;
if labelStr == "Healthy"
    app.Lamp.Color = 'green';
else
    app.Lamp.Color = 'red';
end


app.ExcelSheetCheckBox.Enable= 'on';
app.BarChartCheckBox.Enable='on';
app.ResultsButton.Enable='on';
app.PredictedLabel.Visible = true;
app.PredictedLabel.Text = sprintf('%s', labelStr);
app.AppStatusLabel.Text = sprintf('Classified');
app.statusLamp.Color = 'green'; 


        end

        % Button pushed function: DisplaygraphButton
        function DisplaygraphButtonPushed(app, event)
 % Reuse the dropdown logic instead of duplicating it
        GraphTypeValueChanged(app, []);

        end

        % Value changed function: GraphType
        function GraphTypeValueChanged(app, event)
  value = app.GraphType.Value;

    % Check if audio is loaded
    if isempty(app.audioData)
        uialert(app.UIFigure, 'Please load an audio file first.', 'Error');
        return;
    end

    signal = app.audioData;
    t = (0:length(signal)-1) / app.fs;

    % Reset and clear UIAxes before plotting
    cla(app.UIAxes, 'reset');  % reset removes axis limits + properties
    app.UIAxes.NextPlot = 'replacechildren';  % ensure clean plot
    grid(app.UIAxes, 'on');  % default grid on

    switch value
        case 'Waveform'
            plot(app.UIAxes, t, signal, 'r');
            title(app.UIAxes, 'Waveform');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            app.Slider.Enable = 'on';

        case 'Spectrogram'
            spectrogram(signal, 256, 200, 256, app.fs, 'yaxis');
            title(app.UIAxes, 'Spectrogram');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Frequency (Hz)');
            app.Slider.Enable = 'on';

        case 'Power Spectrum'
            [pxx, f] = pwelch(signal, [], [], [], app.fs);
            plot(app.UIAxes, f, 10*log10(pxx), 'g', 'LineWidth', 1.2);
            title(app.UIAxes, 'Power Spectral Density');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Power/Frequency (dB/Hz)');
            app.Slider.Enable = 'on';
    end

        end

        % Button pushed function: ResultsButton
        function ResultsButtonPushed(app, event)

 % STEP 1: Verify audio is loaded
    if isempty(app.audioFile)
        uialert(app.UIFigure, 'No audio file loaded!', 'Error');
        return;
    end

    % STEP 2: Get model and classify
    modelName = upper(strtrim(app.ModelDropDown.Value));
    audioFullPath = fullfile(app.audioPath, app.audioFile);

    switch modelName
        case 'TREE'
            load('TreeModel.mat', 'TreeModel');
            [label, score] = classifyCompressorFault(audioFullPath, TreeModel);
            classes = TreeModel.ClassificationTree.ClassNames;

        case 'SVM'
            load('bestModel.mat', 'trainedModel');
            [label, score] = classifyCompressorFault(audioFullPath, trainedModel);
            classes = trainedModel.ClassificationSVM.ClassNames;

        case 'ENSEMBLE'
            load('EnsembleModel.mat', 'EnsembleModel');
            [label, score] = classifyCompressorFault(audioFullPath, EnsembleModel);
            classes = EnsembleModel.ClassificationEnsemble.ClassNames;

        otherwise
            uialert(app.UIFigure, 'Unknown model selected!', 'Error');
            return;
    end

    % STEP 3: Normalize scores using softmax if values are negative or not probabilities
    if any(score(:) < 0) || any(score(:) > 1)
        probs = exp(score) ./ sum(exp(score), 2);  % Softmax conversion
    else
        probs = score;
    end

    % STEP 4: Create confidence table
    classNames = cellstr(classes);
    confidenceTable = array2table(probs, 'VariableNames', classNames);
    confidenceTable.AudioFile = string(app.audioFile);
    confidenceTable.PredictedClass = string(label);
    confidenceTable = confidenceTable(:, [end-1, end, 1:end-2]);

    % STEP 5: Save to CSV if checkbox is ticked
    if app.ExcelSheetCheckBox.Value
        writetable(confidenceTable, 'ClassificationResults.csv');
        uialert(app.UIFigure, 'Results saved as "ClassificationResults.csv"', 'Done');
    end

    % STEP 6: Show bar chart if checkbox is ticked
    if app.BarChartCheckBox.Value
        app.Slider.Enable = 'off';  % disable slider for clarity
        cla(app.UIAxes);
        barVals = probs(:) * 100;
        [~, maxIdx] = max(barVals);
        [~, minIdx] = min(barVals);

        barColors = repmat([0.6, 0.6, 0.6], numel(barVals), 1);  % default grey
        barColors(maxIdx, :) = [0, 0.7, 0];  % green
        barColors(minIdx, :) = [1, 0, 0];    % red

        b = bar(app.UIAxes, barVals, 'FaceColor', 'flat');
        b.CData = barColors;

        xticks(app.UIAxes, 1:numel(classNames));
        xticklabels(app.UIAxes, classNames);
        app.UIAxes.XTickLabelRotation = 45;
        ylabel(app.UIAxes, 'Confidence (%)');
        title(app.UIAxes, 'Prediction Confidence');
        ylim(app.UIAxes, [0, 100]);
        grid(app.UIAxes, 'on');
    else
        app.Slider.Enable = 'on';
    end

           
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
 
 % Get slider value (0–100)
    sliderValue = app.Slider.Value;
    graphType = app.GraphType.Value;

    % Exit if no audio loaded
    if isempty(app.audioData)
        uialert(app.UIFigure, 'Please load an audio file first.', 'Error');
        return;
    end

    % Basic setup
    signal = app.audioData;
    app.fs = app.fs;
    t = (0:length(signal)-1) / app.fs;
    totalTime = t(end);

    switch graphType
        case 'Waveform'
            % ✅ Plot only once
            if isempty(app.UIAxes.Children)
                plot(app.UIAxes, t, signal, 'r');
                title(app.UIAxes, 'Waveform');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
                grid(app.UIAxes, 'on');
            end

            % ✅ Apply horizontal zoom using xlim only
            if sliderValue == 0
                xlim(app.UIAxes, [0, totalTime]);  % full view
            else
                zoomFraction = 1 - (sliderValue / 100);  % inverted zoom logic
                visibleTime = max(0.1, totalTime * zoomFraction);  % avoid tiny window

                midTime = totalTime / 2;
                xStart = max(0, midTime - visibleTime / 2);
                xEnd = min(totalTime, midTime + visibleTime / 2);

                xlim(app.UIAxes, [xStart, xEnd]);  % smooth zoom
            end

                case 'Power Spectrum'
            [pxx, f] = pwelch(signal, [], [], [], app.fs);
            maxFreq = max(f);

            % Frequency zoom
            minZoomHz = 0.05;
            zoomFractionHz = 1 - (sliderValue / 100);
            zoomFractionHz = max(minZoomHz, zoomFractionHz);
            visibleFreq = maxFreq * zoomFractionHz;
            scrollFreqStart = (sliderValue / 100) * (maxFreq - visibleFreq);
            scrollFreqEnd = scrollFreqStart + visibleFreq;

            cla(app.UIAxes);
            plot(app.UIAxes, f, 10*log10(pxx), 'g', 'LineWidth', 1.2);
            title(app.UIAxes, 'Power Spectral Density');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Power/Frequency (dB/Hz)');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [scrollFreqStart, scrollFreqEnd]);


        case 'Spectrogram'
            cla(app.UIAxes);
            axes(app.UIAxes);
            spectrogram(signal, 256, 200, 256, app.fs, 'yaxis');
            title(app.UIAxes, 'Spectrogram');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Frequency (Hz)');
    end
        

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
       
            choice = uiconfirm(app.UIFigure, 'Are you sure you want to reset everything?', ...
    'Confirm Reset', 'Options', {'Yes','No'}, 'DefaultOption', 'No');
if strcmp(choice, 'Yes')
    % run your reset logic

    % Clear audio-related variables
    app.audioData = [];
    app.fs = [];
    app.audioFile = '';
    app.audioPath = '';
    app.predictedLabels = categorical();
    app.trueLabels = categorical();
    app.lastExtractedFeatures = [];

    % Clear the plot and reset axes
    cla(app.UIAxes, 'reset');
    title(app.UIAxes, 'Waveform');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    grid(app.UIAxes, 'on');

    % Reset dropdowns and slider
    app.ModelDropDown.Value = 'Tree';
    app.GraphType.Value = 'Waveform';
    app.Slider.Value = 0;
    app.Slider.Enable = 'on';

    % Reset label and lamp
    app.PredictedLabel.Text = 'Predicted Label';
    app.PredictedLabel.FontColor = [0.5 0.5 0.5]; % gray
    app.statusLamp.Color = [0.5 0.5 0.5];
    app.AppStatusLabel.Text = 'Ready';
    app.AppStatusLabel.FontColor = [0.5 0.5 0.5];

    % Disable Results + checkboxes
    app.ResultsButton.Enable = 'off';
    app.ExcelSheetCheckBox.Enable = 'off';
    app.ExcelSheetCheckBox.Value = false;
    app.BarChartCheckBox.Enable = 'off';
    app.BarChartCheckBox.Value = false;
    app.PredictedLabel.Visible = 'off';
    app.Lamp.Visible ='off';
end
        end

        % Callback function: not associated with a component
        function DarkModeSwitchValueChanged(app, event)
              applyThemeApp(app);
            
        end

        % Callback function: not associated with a component
        function DarkModeSwitchValueChanged2(app, event)
            
          
        end

        % Callback function: not associated with a component
        function ApplyThemeApp(app, event)


        end

        % Callback function: not associated with a component
        function DarkModeCheckBoxValueChanged(app, event)
        isDark = app.DarkModeCheckBox.Value;

    if isDark
        %% DARK MODE STYLING
        app.UIFigure.Color = [0.1 0.1 0.1];

        % Panels
        app.MainControlPanel.BackgroundColor = [0.1 0.1 0.1];
        app.DataVisualisationPanel.BackgroundColor = [0.1 0.1 0.1];
        app.AdvancedFunctionsPanel.BackgroundColor = [0.1 0.1 0.1];

        % Labels
        app.AppStatusLabel.FontColor = [1 1 1];
        app.PredictedLabel.FontColor = [1 1 1];
        app.DefectDetectionAppPanel.ForegroundColor = [1 1 1];
        app.MainControlPanel.ForegroundColor = [1 1 1];
        app.DataVisualisationPanel.ForegroundColor = [1 1 1];
        app.AdvancedFunctionsPanel.ForegroundColor = [1 1 1];

        % Axes
        app.UIAxes.Color = [0 0 0];
        app.UIAxes.XColor = [1 1 1];
        app.UIAxes.YColor = [1 1 1];
        app.UIAxes.XLabel.Color = [1 1 1];
        app.UIAxes.YLabel.Color = [1 1 1];
        app.UIAxes.Title.Color = [1 1 1];

        % Buttons
        darkBtnBG = [0 0 0];
        darkBtnFG = [1 1 1];
        btns = [app.LoadAudioButton, app.ClassifyaudioButton, app.PlaybackButton, app.DisplaygraphButton, app.ResetButton, app.ResultsButton];
        for i = 1:numel(btns)
            btns(i).BackgroundColor = darkBtnBG;
            btns(i).FontColor = darkBtnFG;
        end

        % Dropdowns
        dropDowns = [app.ModelDropDown, app.GraphType];
        for i = 1:numel(dropDowns)
            dropDowns(i).BackgroundColor = [0 0 0];
            dropDowns(i).FontColor = [1 1 1];
        end

    else
        %% LIGHT MODE STYLING
        app.UIFigure.Color = [1 1 1];

        % Panels
        app.MainControlPanel.BackgroundColor = [1 1 1];
        app.DataVisualisationPanel.BackgroundColor = [1 1 1];
        app.AdvancedFunctionsPanel.BackgroundColor = [1 1 1];

        % Labels
        app.AppStatusLabel.FontColor = [0 0 0];
        app.PredictedLabel.FontColor = [0 0 0];
        app.DefectDetectionAppPanel.ForegroundColor = [0 0 0];
        app.MainControlPanel.ForegroundColor = [0 0 0];
        app.DataVisualisationPanel.ForegroundColor = [0 0 0];
        app.AdvancedFunctionsPanel.ForegroundColor = [0 0 0];

        % Axes
        app.UIAxes.Color = [1 1 1];
        app.UIAxes.XColor = [0 0 0];
        app.UIAxes.YColor = [0 0 0];
        app.UIAxes.XLabel.Color = [0 0 0];
        app.UIAxes.YLabel.Color = [0 0 0];
        app.UIAxes.Title.Color = [0 0 0];

        % Buttons
        lightBtnBG = [1 1 1];
        lightBtnFG = [0 0 0];
        btns = [app.LoadAudioButton, app.ClassifyaudioButton, app.PlaybackButton, app.DisplaygraphButton, app.ResetButton, app.ResultsButton];
        for i = 1:numel(btns)
            btns(i).BackgroundColor = lightBtnBG;
            btns(i).FontColor = lightBtnFG;
        end

        % Dropdowns
        dropDowns = [app.ModelDropDown, app.GraphType];
        for i = 1:numel(dropDowns)
            dropDowns(i).BackgroundColor = [1 1 1];
            dropDowns(i).FontColor = [0 0 0];
        end
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DefectDetectionAppPanel = uipanel(app.UIFigure);
            app.DefectDetectionAppPanel.Title = 'Defect Detection App';
            app.MainControlPanel = uipanel(app.DefectDetectionAppPanel);
            app.MainControlPanel.Title = 'Main Control';
            app.LoadAudioButton = uibutton(app.MainControlPanel, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Tooltip = {'Load Audio from library'};
            app.LoadAudioButton.Text = 'Load Audio';
            app.ClassifyaudioButton = uibutton(app.MainControlPanel, 'push');
            app.ClassifyaudioButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyaudioButtonPushed, true);
            app.ClassifyaudioButton.Tooltip = {'Shows the air compressor fault'};
            app.ClassifyaudioButton.Text = 'Classify audio';
            app.PlaybackButton = uibutton(app.MainControlPanel, 'push');
            app.PlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackButtonPushed, true);
            app.PlaybackButton.Tooltip = {'Plays the loaded audio'};
            app.PlaybackButton.Text = 'Playback';
            app.ModelDropDown = uidropdown(app.MainControlPanel);
            app.ModelDropDown.Items = {'Tree', 'SVM', 'Ensemble'};
            app.ModelDropDown.Value = 'Tree';
            app.DisplaygraphButton = uibutton(app.MainControlPanel, 'push');
            app.DisplaygraphButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaygraphButtonPushed, true);
            app.DisplaygraphButton.Tooltip = {'Displays graph chosen from dropdown menu'};
            app.DisplaygraphButton.Text = 'Display graph';
            app.ResetButton = uibutton(app.MainControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset the whole process'};
            app.ResetButton.Text = 'Reset';
            app.DataVisualisationPanel = uipanel(app.DefectDetectionAppPanel);
            app.DataVisualisationPanel.Title = 'Data Visualisation';
            app.UIAxes = uiaxes(app.DataVisualisationPanel);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraphType = uidropdown(app.DataVisualisationPanel);
            app.GraphType.Items = {'Waveform', 'Spectrogram', 'Power Spectrum'};
            app.GraphType.ValueChangedFcn = createCallbackFcn(app, @GraphTypeValueChanged, true);
            app.GraphType.Tooltip = {'Select graph type to be displayed'};
            app.GraphType.Value = 'Waveform';
            app.Slider = uislider(app.DataVisualisationPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tooltip = {'Minimize or Maximize graph '};
            app.AppStatusLabel = uilabel(app.DefectDetectionAppPanel);
            app.AppStatusLabel.Tooltip = {'Application Status'};
            app.AppStatusLabel.Text = 'App Status';
            app.Lamp = uilamp(app.DefectDetectionAppPanel);
            app.Lamp.Visible = 'off';
            app.PredictedLabel = uilabel(app.DefectDetectionAppPanel);
            app.PredictedLabel.Visible = 'off';
            app.PredictedLabel.Text = 'Predicted Label';
            app.AdvancedFunctionsPanel = uipanel(app.DefectDetectionAppPanel);
            app.AdvancedFunctionsPanel.Title = 'Advanced Functions';
            app.ResultsButton = uibutton(app.AdvancedFunctionsPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.ResultsButton.Enable = 'off';
            app.ResultsButton.Tooltip = {'displays confidence scores of Predicted labels'};
            app.ResultsButton.Text = 'Results';
            app.BarChartCheckBox = uicheckbox(app.AdvancedFunctionsPanel);
            app.BarChartCheckBox.Enable = 'off';
            app.BarChartCheckBox.Tooltip = {'displays bar chart of Predicted labels confidence'};
            app.BarChartCheckBox.Text = 'Bar Chart';
            app.ExcelSheetCheckBox = uicheckbox(app.AdvancedFunctionsPanel);
            app.ExcelSheetCheckBox.Enable = 'off';
            app.ExcelSheetCheckBox.Tooltip = {'Exports excel sheet for confidence scores of predicted Labels'};
            app.ExcelSheetCheckBox.Text = 'Excel Sheet';
            app.statusLamp = uilamp(app.DefectDetectionAppPanel);
            app.statusLamp.Tooltip = {'Lamp status'; 'Green: successful'; 'grey: Ready to operate'; 'yellow: Audio is Loaded'; 'Red: Cancellation of file uploading'};
            app.DefectDetectorAllRightsReservedLabel = uilabel(app.DefectDetectionAppPanel);
            app.DefectDetectorAllRightsReservedLabel.Text = '© 2025 Defect Detector | All Rights Reserved |';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end